classdef StairsGeometryApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GenerateCutMarkingsButton      matlab.ui.control.Button
        PlotGeometryButton             matlab.ui.control.Button
        stairsGeometryCommandWindowButton  matlab.ui.control.Button
        StringerThicknessinEditField   matlab.ui.control.NumericEditField
        StringerThicknessinEditFieldLabel  matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        FinishedMaterialBottomFloorThicknessinEditField  matlab.ui.control.NumericEditField
        FinishedMaterialBottomFloorThicknessinEditFieldLabel  matlab.ui.control.Label
        TreadFinishedMaterialThicknessinEditField  matlab.ui.control.NumericEditField
        TreadFinishedMaterialThicknessinEditFieldLabel  matlab.ui.control.Label
        RiserFinishedMaterialThicknessinEditField  matlab.ui.control.NumericEditField
        RiserFinishedMaterialThicknessinEditFieldLabel  matlab.ui.control.Label
        NumberStepsEditField           matlab.ui.control.NumericEditField
        NumberStepsEditFieldLabel      matlab.ui.control.Label
        RuninEditField                 matlab.ui.control.NumericEditField
        RuninEditFieldLabel            matlab.ui.control.Label
        RiseinEditField                matlab.ui.control.NumericEditField
        RiseinEditFieldLabel           matlab.ui.control.Label
        StairsGeometryLabel            matlab.ui.control.Label
        UpdateDerivedParametersButton  matlab.ui.control.Button
        UIAxesOverallGeometry          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stairsGeometry % StairsGeometry object
    end
    
    methods (Access = private)
        
        function [] = updateUiBasedOnStairsGeometryProperty(app)
            %Update various UI parameters based on what is stored in the
            %stairsGeometry property
            app.RiseinEditField.Value                                   = app.stairsGeometry.rise;
            app.RuninEditField.Value                                    = app.stairsGeometry.run;
            app.NumberStepsEditField.Value                              = app.stairsGeometry.numSteps;
            app.RiserFinishedMaterialThicknessinEditField.Value         = app.stairsGeometry.tR;
            app.TreadFinishedMaterialThicknessinEditField.Value         = app.stairsGeometry.tT;
            app.FinishedMaterialBottomFloorThicknessinEditField.Value   = app.stairsGeometry.tFMBF;
            app.StringerThicknessinEditField.Value                      = app.stairsGeometry.tS;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rise_in = 105;
            run_in = 150;
            numSteps = 12;
            tR_in = 0.75;
            tT_in = 2;
            tFMBF_in = 4.5;
            tS_in = 11.25;

            [stairsGeometryObj] = StairsGeometry(rise_in,run_in,numSteps,tR_in,tT_in,tFMBF_in,tS_in);
            app.stairsGeometry = stairsGeometryObj;
            app.StatusEditField.Value = 'Initialized stairsGeometry property';

            app.updateUiBasedOnStairsGeometryProperty();
        end

        % Button pushed function: UpdateDerivedParametersButton
        function UpdateDerivedParametersButtonPushed(app, event)
            rise_in     = app.RiseinEditField.Value;
            run_in      = app.RuninEditField.Value;
            numSteps    = app.NumberStepsEditField.Value;
            tR_in       = app.RiserFinishedMaterialThicknessinEditField.Value;
            tT_in       = app.TreadFinishedMaterialThicknessinEditField.Value;
            tFMBF_in    = app.FinishedMaterialBottomFloorThicknessinEditField.Value;
            tS_in       = app.StringerThicknessinEditField.Value;

            %Perform various checks on the input number
            isValid = true;

            %Check that the number is in the admisslble range
            if (rise_in<=0) || ...
                    (run_in<=0) || ...
                    (numSteps<=1) || ...
                    (tR_in<=0) || ...
                    (tT_in<=0) || ...
                    (tFMBF_in<=0) || ...
                    (tS_in<=0)
                isValid = false;
            end

            %Check that the number is an integer
            if(~isinteger2(numSteps))
                isValid = false;
            end

            if isValid
                app.StatusEditField.Value= 'Updating derived parameters based on current values';

                %Create a new StairsGeometry object
                [stairsGeometryObj] = StairsGeometry(rise_in,run_in,numSteps,tR_in,tT_in,tFMBF_in,tS_in);
                app.stairsGeometry = stairsGeometryObj;

                app.updateUiBasedOnStairsGeometryProperty();

            else
                %Invalid number, ignore
                app.StatusEditField.Value= 'One or more of the parameters are invalid!';

            end            
        end

        % Button pushed function: stairsGeometryCommandWindowButton
        function stairsGeometryCommandWindowButtonPushed(app, event)
            %Simply display to command window
            app.stairsGeometry
        end

        % Button pushed function: PlotGeometryButton
        function PlotGeometryButtonPushed(app, event)
            app.stairsGeometry.PlotStairsGeometry()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOverallGeometry = uiaxes(app.UIFigure);
            title(app.UIAxesOverallGeometry, 'Title')
            xlabel(app.UIAxesOverallGeometry, 'X')
            ylabel(app.UIAxesOverallGeometry, 'Y')
            zlabel(app.UIAxesOverallGeometry, 'Z')
            app.UpdateDerivedParametersButton = uibutton(app.UIFigure, 'push');
            app.UpdateDerivedParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDerivedParametersButtonPushed, true);
            app.UpdateDerivedParametersButton.Text = 'Update Derived Parameters';
            app.StairsGeometryLabel = uilabel(app.UIFigure);
            app.StairsGeometryLabel.Text = 'Stairs Geometry';
            app.RiseinEditFieldLabel = uilabel(app.UIFigure);
            app.RiseinEditFieldLabel.Text = 'Rise (in)';
            app.RiseinEditField = uieditfield(app.UIFigure, 'numeric');
            app.RuninEditFieldLabel = uilabel(app.UIFigure);
            app.RuninEditFieldLabel.Text = 'Run (in)';
            app.RuninEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberStepsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberStepsEditFieldLabel.Text = 'Number Steps';
            app.NumberStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RiserFinishedMaterialThicknessinEditFieldLabel = uilabel(app.UIFigure);
            app.RiserFinishedMaterialThicknessinEditFieldLabel.Text = 'Riser Finished Material Thickness (in)';
            app.RiserFinishedMaterialThicknessinEditField = uieditfield(app.UIFigure, 'numeric');
            app.TreadFinishedMaterialThicknessinEditFieldLabel = uilabel(app.UIFigure);
            app.TreadFinishedMaterialThicknessinEditFieldLabel.Text = 'Tread Finished Material Thickness (in)';
            app.TreadFinishedMaterialThicknessinEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinishedMaterialBottomFloorThicknessinEditFieldLabel = uilabel(app.UIFigure);
            app.FinishedMaterialBottomFloorThicknessinEditFieldLabel.Text = 'Finished Material Bottom Floor Thickness (in)';
            app.FinishedMaterialBottomFloorThicknessinEditField = uieditfield(app.UIFigure, 'numeric');
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StringerThicknessinEditFieldLabel = uilabel(app.UIFigure);
            app.StringerThicknessinEditFieldLabel.Text = 'Stringer Thickness (in)';
            app.StringerThicknessinEditField = uieditfield(app.UIFigure, 'numeric');
            app.stairsGeometryCommandWindowButton = uibutton(app.UIFigure, 'push');
            app.stairsGeometryCommandWindowButton.ButtonPushedFcn = createCallbackFcn(app, @stairsGeometryCommandWindowButtonPushed, true);
            app.stairsGeometryCommandWindowButton.Text = 'stairsGeometry Command Window';
            app.PlotGeometryButton = uibutton(app.UIFigure, 'push');
            app.PlotGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGeometryButtonPushed, true);
            app.PlotGeometryButton.Text = 'Plot Geometry';
            app.GenerateCutMarkingsButton = uibutton(app.UIFigure, 'push');
            app.GenerateCutMarkingsButton.Text = 'Generate Cut Markings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StairsGeometryApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end