classdef App02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        BingoRangesTab               matlab.ui.container.Tab
        OmaxEditField                matlab.ui.control.NumericEditField
        OmaxEditFieldLabel           matlab.ui.control.Label
        OminEditField                matlab.ui.control.NumericEditField
        OminEditFieldLabel           matlab.ui.control.Label
        GmaxEditField                matlab.ui.control.NumericEditField
        GmaxEditFieldLabel           matlab.ui.control.Label
        GminEditField                matlab.ui.control.NumericEditField
        GminEditFieldLabel           matlab.ui.control.Label
        NmaxEditField                matlab.ui.control.NumericEditField
        NmaxEditFieldLabel           matlab.ui.control.Label
        NminEditField                matlab.ui.control.NumericEditField
        NminEditFieldLabel           matlab.ui.control.Label
        ImaxEditField                matlab.ui.control.NumericEditField
        ImaxEditFieldLabel           matlab.ui.control.Label
        IminEditField                matlab.ui.control.NumericEditField
        IminEditFieldLabel           matlab.ui.control.Label
        BmaxEditField                matlab.ui.control.NumericEditField
        BmaxEditFieldLabel           matlab.ui.control.Label
        BminEditField                matlab.ui.control.NumericEditField
        BminEditFieldLabel           matlab.ui.control.Label
        Tab2                         matlab.ui.container.Tab
        NumbersCalledEditField       matlab.ui.control.EditField
        NumbersCalledEditFieldLabel  matlab.ui.control.Label
        StatusEditField              matlab.ui.control.EditField
        StatusEditFieldLabel         matlab.ui.control.Label
        DeleteButton                 matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        CurrentNumberEditField       matlab.ui.control.NumericEditField
        CurrentNumberEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        numbersCalled; % vector of numbers called
    end
    
    methods (Access = private)
        
        function [] = updateNumbersCalledEditField(app)
            s = num2str(app.numbersCalled');
            app.NumbersCalledEditField.Value = s;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numbersCalled = [];

            %Set ranges for BINGO
            app.BminEditField.Value = 1;
            app.BmaxEditField.Value = 15;
            
            app.IminEditField.Value = 16;
            app.ImaxEditField.Value = 30;

            app.NminEditField.Value = 31;
            app.NmaxEditField.Value = 45;

            app.GminEditField.Value = 46;
            app.GmaxEditField.Value = 60;

            app.OminEditField.Value = 61;
            app.OmaxEditField.Value = 75;

            %Place a random number in the current number
            app.CurrentNumberEditField.Value = ceil(75*rand(1));


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.StatusEditField.Value = "Pressed 'Add' button";

            %Concatenate number
            currentNumber = app.CurrentNumberEditField.Value;

            if(currentNumber>=app.BminEditField.Value && currentNumber<=app.OmaxEditField.Value)
                [app.numbersCalled] = ConcatenateCurrentNumberAndOldNumbers(currentNumber,app.numbersCalled);
            else
                app.StatusEditField.Value = "Error!  Current number out of range";
            end
            
            %Update UI
            app.updateNumbersCalledEditField();
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.StatusEditField.Value = "Pressed 'Delete' button";

            %Delete number
            currentNumber = app.CurrentNumberEditField.Value;
            [app.numbersCalled] = DeleteNumbers(currentNumber,app.numbersCalled);

            %Update UI
            app.updateNumbersCalledEditField();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentNumberEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentNumberEditFieldLabel.Text = 'Current Number';
            app.CurrentNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.NumbersCalledEditFieldLabel = uilabel(app.UIFigure);
            app.NumbersCalledEditFieldLabel.Text = 'Numbers Called';
            app.NumbersCalledEditField = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BingoRangesTab = uitab(app.TabGroup);
            app.BingoRangesTab.Title = 'Bingo Ranges';
            app.BminEditFieldLabel = uilabel(app.BingoRangesTab);
            app.BminEditFieldLabel.Text = 'B min';
            app.BminEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.BmaxEditFieldLabel = uilabel(app.BingoRangesTab);
            app.BmaxEditFieldLabel.Text = 'B max';
            app.BmaxEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.IminEditFieldLabel = uilabel(app.BingoRangesTab);
            app.IminEditFieldLabel.Text = 'I min';
            app.IminEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.ImaxEditFieldLabel = uilabel(app.BingoRangesTab);
            app.ImaxEditFieldLabel.Text = 'I max';
            app.ImaxEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.NminEditFieldLabel = uilabel(app.BingoRangesTab);
            app.NminEditFieldLabel.Text = 'N min';
            app.NminEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.NmaxEditFieldLabel = uilabel(app.BingoRangesTab);
            app.NmaxEditFieldLabel.Text = 'N max';
            app.NmaxEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.GminEditFieldLabel = uilabel(app.BingoRangesTab);
            app.GminEditFieldLabel.Text = 'G min';
            app.GminEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.GmaxEditFieldLabel = uilabel(app.BingoRangesTab);
            app.GmaxEditFieldLabel.Text = 'G max';
            app.GmaxEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.OminEditFieldLabel = uilabel(app.BingoRangesTab);
            app.OminEditFieldLabel.Text = 'O min';
            app.OminEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.OmaxEditFieldLabel = uilabel(app.BingoRangesTab);
            app.OmaxEditFieldLabel.Text = 'O max';
            app.OmaxEditField = uieditfield(app.BingoRangesTab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end