classdef App03_UIAxes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GetAxesHandleButton       matlab.ui.control.Button
        AxesHandleEditField       matlab.ui.control.EditField
        AxesHandleEditFieldLabel  matlab.ui.control.Label
        PlotViaFunctionButton     matlab.ui.control.Button
        PlotPatchButton           matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        AEditField                matlab.ui.control.NumericEditField
        AEditFieldLabel           matlab.ui.control.Label
        wradsEditField            matlab.ui.control.NumericEditField
        wradsEditFieldLabel       matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        %Compute the maximum x value so we display 3 full oscillations
        function [xMax] = getXMax(app)
            w = app.wradsEditField.Value;
            f = w/(2*pi);
            T = 1/f;

            numOscillations = 3;

            xMax = T*numOscillations;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AEditField.Value        = 2.5;
            app.wradsEditField.Value    = 0.5;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            A = app.AEditField.Value;
            w = app.wradsEditField.Value;

            t = linspace(0,app.getXMax(),300);
            ySin = A*sin(w*t);
            yCos = A*cos(w*t);

            %Plot the signals
            %Note: need to specify app.UIAxes.  If you don't use this this will generate a new plot in a separate window
            hold(app.UIAxes,'on');
            plot(app.UIAxes,t,ySin,'DisplayName','A*sin(w*t)')
            plot(app.UIAxes,t,yCos,'DisplayName','A*cos(w*t)')

            legend(app.UIAxes,'Location','Best')
            xlabel(app.UIAxes,'t (sec)');
            ylabel(app.UIAxes,'y')
        end

        % Button pushed function: PlotPatchButton
        function PlotPatchButtonPushed(app, event)
            
            hold(app.UIAxes,'on')
            
            x1 = [0 1 1 0];
            y1 = [0 0 1 1];
            patch(app.UIAxes2,x1,y1,'g');
            
            x2 = [0 1 1 0] + 2;
            y2 = [0 0 1 1] + 1;
            patch(app.UIAxes2,x2,y2,'m');
            
            
        end

        % Button pushed function: PlotViaFunctionButton
        function PlotViaFunctionButtonPushed(app, event)
            x = 1;
            y = 2;
            c = 'g';
            
            %Set the axes
            hold(app.UIAxes3,'on')
            axes(app.UIAxes3);
            
            figh = gca;
            
%             PlotCustomFunction(x,y,c);
            PlotCustomFunctionUIAxes(x,y,c,app.UIAxes3);
        end

        % Button pushed function: GetAxesHandleButton
        function GetAxesHandleButtonPushed(app, event)
            figh = app.UIAxes3;
            app.AxesHandleEditField.Value = figh.Title.String;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'sin and cos')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Patch')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Plot  Via Function')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.wradsEditFieldLabel = uilabel(app.UIFigure);
            app.wradsEditFieldLabel.Text = 'w (rad/s)';
            app.wradsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotPatchButton = uibutton(app.UIFigure, 'push');
            app.PlotPatchButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPatchButtonPushed, true);
            app.PlotPatchButton.Text = 'Plot Patch';
            app.PlotViaFunctionButton = uibutton(app.UIFigure, 'push');
            app.PlotViaFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotViaFunctionButtonPushed, true);
            app.PlotViaFunctionButton.Text = 'Plot Via Function';
            app.AxesHandleEditFieldLabel = uilabel(app.UIFigure);
            app.AxesHandleEditFieldLabel.Text = 'Axes Handle';
            app.AxesHandleEditField = uieditfield(app.UIFigure, 'text');
            app.GetAxesHandleButton = uibutton(app.UIFigure, 'push');
            app.GetAxesHandleButton.ButtonPushedFcn = createCallbackFcn(app, @GetAxesHandleButtonPushed, true);
            app.GetAxesHandleButton.Text = 'Get Axes Handle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App03_UIAxes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end